classdef Exam1_Snyder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TwoFrameBowlin   matlab.ui.Figure
        MainGrid         matlab.ui.container.GridLayout
        CALCULATEButton  matlab.ui.control.Button
        RESETButton      matlab.ui.control.Button
        Frame2Panel      matlab.ui.container.Panel
        frame2Grid       matlab.ui.container.GridLayout
        frame2Score      matlab.ui.control.Label
        frame2ball2      matlab.ui.control.NumericEditField
        frame2ball1      matlab.ui.control.NumericEditField
        Frame1Panel      matlab.ui.container.Panel
        frame1Grid       matlab.ui.container.GridLayout
        frame1ball2      matlab.ui.control.NumericEditField
        frame1ball1      matlab.ui.control.NumericEditField
        frame1Score      matlab.ui.control.Label
    end

    
    properties (Access = private)
        isSpare = false;    %bool for if a spare is made
        isStrike = false;   %bool for if a strike is made
        sumFrame1 = 0;      %track frame1 score
        sumFrame2 = 0;      %track frame2 score
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            app.sumFrame1 = app.frame1ball1.Value + app.frame1ball2.Value;
            app.sumFrame2 = app.frame2ball1.Value + app.frame2ball2.Value;
            if app.frame1ball1.Value == 10
                app.isStrike = true;
            elseif (app.sumFrame1) == 10
                if app.isStrike == false
                    app.isSpare = true;
                end
            end
            if app.isStrike
                app.sumFrame1 = app.sumFrame1 + app.sumFrame2;
                app.frame1Score.Text = num2str(app.sumFrame1);
            elseif app.isSpare
                app.sumFrame1 = app.sumFrame1 + app.frame2ball1.Value;
                app.frame1Score.Text = num2str(app.sumFrame1);
            else
                app.frame1Score.Text = num2str(app.sumFrame1);
            end
            app.frame2Score.Text = num2str(app.sumFrame1 + app.sumFrame2);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.sumFrame1 = 0;
            app.sumFrame2 = 0;
            app.isSpare = false;
            app.isStrike = false;
            app.frame1Score.Text = "0";
            app.frame2Score.Text = "0";
            app.frame1ball1.Value = 0;
            app.frame1ball2.Value = 0;
            app.frame2ball1.Value = 0;
            app.frame2ball2.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TwoFrameBowlin = uifigure('Visible', 'off');
            app.TwoFrameBowlin.Name = 'Two Frame Bowlin';
            app.MainGrid = uigridlayout(app.TwoFrameBowlin);
            app.Frame1Panel = uipanel(app.MainGrid);
            app.Frame1Panel.Title = 'Frame 1';
            app.frame1Grid = uigridlayout(app.Frame1Panel);
            app.frame1Score = uilabel(app.frame1Grid);
            app.frame1Score.Text = '0';
            app.frame1ball1 = uieditfield(app.frame1Grid, 'numeric');
            app.frame1ball2 = uieditfield(app.frame1Grid, 'numeric');
            app.Frame2Panel = uipanel(app.MainGrid);
            app.Frame2Panel.Title = 'Frame 2';
            app.frame2Grid = uigridlayout(app.Frame2Panel);
            app.frame2ball1 = uieditfield(app.frame2Grid, 'numeric');
            app.frame2ball2 = uieditfield(app.frame2Grid, 'numeric');
            app.frame2Score = uilabel(app.frame2Grid);
            app.frame2Score.Text = '0';
            app.RESETButton = uibutton(app.MainGrid, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.CALCULATEButton = uibutton(app.MainGrid, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            % Show the figure after all components are created
            app.TwoFrameBowlin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam1_Snyder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TwoFrameBowlin)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TwoFrameBowlin)
        end
    end
end