classdef simonRemote < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimonRemoteUIFigure   matlab.ui.Figure
        RangeofMotionDemonstrationButton  matlab.ui.control.Button
        SavedPositionsPanel   matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        GotoPosition3Button   matlab.ui.control.Button
        GotoPosition2Button   matlab.ui.control.Button
        GotoPosition1Button   matlab.ui.control.Button
        SavePosition3Button   matlab.ui.control.Button
        SavePosition2Button   matlab.ui.control.Button
        SavePosition1Button   matlab.ui.control.Button
        ManualInputsPanel     matlab.ui.container.Panel
        Joint4EditField       matlab.ui.control.NumericEditField
        Joint4EditFieldLabel  matlab.ui.control.Label
        GoButton              matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        Joint3EditField       matlab.ui.control.NumericEditField
        Joint3EditFieldLabel  matlab.ui.control.Label
        Joint2EditField       matlab.ui.control.NumericEditField
        Joint2EditFieldLabel  matlab.ui.control.Label
        Joint1EditField       matlab.ui.control.NumericEditField
        Joint1EditFieldLabel  matlab.ui.control.Label
        SlidersPanel          matlab.ui.container.Panel
        Joint4SliderLabel     matlab.ui.control.Label
        Joint4Slider          matlab.ui.control.Slider
        Joint3SliderLabel     matlab.ui.control.Label
        Joint3Slider          matlab.ui.control.Slider
        Joint2SliderLabel     matlab.ui.control.Label
        Joint2Slider          matlab.ui.control.Slider
        Joint1SliderLabel     matlab.ui.control.Label
        Joint1Slider          matlab.ui.control.Slider
        axes_arm              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        
        jointAngles = [0,90,90,15];
        pos1 = [0,90,90,15];
        pos2 = [0,90,90,15];
        pos3 = [0,90,90,15];

        link1Patch
        link2Patch
        link3Patch
        link4Patch

        link1Vertices
        link2Vertices
        link3Vertices
        link4Vertices

    end

    methods (Access = private)

        function updateView(app)
            app.updateSliders()
            app.updateManualInputs()
            app.updateArm()
        end
        function updateSliders(app)
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointAngles(4);
        end
        function updateManualInputs(app)
            app.Joint1EditField.Value = app.jointAngles(1);
            app.Joint2EditField.Value = app.jointAngles(2);
            app.Joint3EditField.Value = app.jointAngles(3);
            app.Joint4EditField.Value = app.jointAngles(4);
        end
        function updateArm(app)
            % Create the A homogeneous transformation matrices for the given jointAngles.
            [A1,A2,A3,A4]=create_Simon_A_matrices(app.jointAngles);

            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;
            T0_4 = A1*A2*A3*A4;

            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;

            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)'); %app.link1Patch.Vertices
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc            
            % Prepare the axes_arm properties
            %             app.axes_arm.View = [90 -50];
            app.axes_arm.View = [45 45];
            app.axes_arm.Visible = 'off';


            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-125 125];
            app.axes_arm.YLim = [-125 125];
            app.axes_arm.ZLim = [0 175];

            % Create vertices for all the patches
            makeStlLink("SimonHome - SimonBase-1.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("SimonHome - SimonLink1-1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("SimonHome - SimonLink2-1.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("SimonHome - SimonLink3-1.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("SimonHome - SimonLink4-1.stl", app.axes_arm, [24/255 17/255 89/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));
            % End: Code that can go into your GUI's opening function.

            updateArm(app);
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.updateView();
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.updateView();
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.updateView();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            jointAnglesReset = [0,90,90,15];
            l1toOld = linspace(app.jointAngles(1),jointAnglesReset(1),100);
            l2toOld = linspace(app.jointAngles(2),jointAnglesReset(2),100);
            l3toOld = linspace(app.jointAngles(3),jointAnglesReset(3),100);
            l4toOld = linspace(app.jointAngles(4),jointAnglesReset(4),100);
            for k = 1:100
                app.jointAngles = [l1toOld(k),l2toOld(k),l3toOld(k),l4toOld(k)];
                disp(app.jointAngles);
                app.updateView();
                pause(0.005);
            end
            app.updateView();
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
%             app.jointAngles(1) = app.Joint1EditField.Value;
%             app.jointAngles(2) = app.Joint2EditField.Value;
%             app.jointAngles(3) = app.Joint3EditField.Value;
%             app.jointAngles(4) = app.Joint4EditField.Value;
            l1toNew = linspace(app.jointAngles(1),app.Joint1EditField.Value,100);
            l2toNew = linspace(app.jointAngles(2),app.Joint2EditField.Value,100);
            l3toNew = linspace(app.jointAngles(3),app.Joint3EditField.Value,100);
            l4toNew = linspace(app.jointAngles(4),app.Joint4EditField.Value,100);
            for k = 1:100
                app.jointAngles = [l1toNew(k),l2toNew(k),l3toNew(k),l4toNew(k)];
                disp(app.jointAngles);
                app.updateView();
                pause(0.005);
            end
%             app.updateView();
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(4) = changingValue;
            app.updateView();
        end

        % Button pushed function: SavePosition1Button
        function SavePosition1ButtonPushed(app, event)
            app.pos1 = [app.Joint1Slider.Value,app.Joint2Slider.Value,...
                app.Joint3Slider.Value,app.Joint4Slider.Value];
        end

        % Button pushed function: SavePosition2Button
        function SavePosition2ButtonPushed(app, event)
            app.pos2 = [app.Joint1Slider.Value,app.Joint2Slider.Value,...
                app.Joint3Slider.Value,app.Joint4Slider.Value];
        end

        % Button pushed function: SavePosition3Button
        function SavePosition3ButtonPushed(app, event)
            app.pos3 = [app.Joint1Slider.Value,app.Joint2Slider.Value,...
                app.Joint3Slider.Value,app.Joint4Slider.Value];
        end

        % Button pushed function: GotoPosition1Button
        function GotoPosition1ButtonPushed(app, event)
            %app.jointAngles = app.pos1;
            %app.updateView();
            l1toNew = linspace(app.jointAngles(1),app.pos1(1),100);
            l2toNew = linspace(app.jointAngles(2),app.pos1(2),100);
            l3toNew = linspace(app.jointAngles(3),app.pos1(3),100);
            l4toNew = linspace(app.jointAngles(4),app.pos1(4),100);
            for k = 1:100
                app.jointAngles = [l1toNew(k),l2toNew(k),l3toNew(k),l4toNew(k)];
                app.updateView();
                pause(0.005);
            end
        end

        % Button pushed function: GotoPosition2Button
        function GotoPosition2ButtonPushed(app, event)
%             app.jointAngles = app.pos2;
%             app.updateView();
            l1toNew = linspace(app.jointAngles(1),app.pos2(1),100);
            l2toNew = linspace(app.jointAngles(2),app.pos2(2),100);
            l3toNew = linspace(app.jointAngles(3),app.pos2(3),100);
            l4toNew = linspace(app.jointAngles(4),app.pos2(4),100);
            for k = 1:100
                app.jointAngles = [l1toNew(k),l2toNew(k),l3toNew(k),l4toNew(k)];
                app.updateView();
                pause(0.005);
            end
        end

        % Button pushed function: GotoPosition3Button
        function GotoPosition3ButtonPushed(app, event)
%             app.jointAngles = app.pos3;
%             app.updateView();
            l1toNew = linspace(app.jointAngles(1),app.pos3(1),100);
            l2toNew = linspace(app.jointAngles(2),app.pos3(2),100);
            l3toNew = linspace(app.jointAngles(3),app.pos3(3),100);
            l4toNew = linspace(app.jointAngles(4),app.pos3(4),100);
            for k = 1:100
                app.jointAngles = [l1toNew(k),l2toNew(k),l3toNew(k),l4toNew(k)];
                app.updateView();
                pause(0.005);
            end
        end

        % Button pushed function: RangeofMotionDemonstrationButton
        function romButtonPushed(app, event)
            app.jointAngles = [0,90,90,15];
            %% Link 1
            for i=-180:180
                app.jointAngles = [i,app.jointAngles(2),app.jointAngles(3),app.jointAngles(4)];
                app.updateView();
                pause(0.005);
            end
            %% Link 1
            %% Link 2
            for i=90:-1:0
                app.jointAngles = [app.jointAngles(1),i,app.jointAngles(3),app.jointAngles(4)];
                app.updateView();
                pause(0.005);
            end
            for i=0:180
                app.jointAngles = [app.jointAngles(1),i,app.jointAngles(3),app.jointAngles(4)];
                app.updateView();
                pause(0.005);
            end
            %% Link 2
            %% Link 3
            for i=90:-1:0
                app.jointAngles = [app.jointAngles(1),app.jointAngles(2),i,app.jointAngles(4)];
                app.updateView();
                pause(0.005);
            end
            for i=0:180
                app.jointAngles = [app.jointAngles(1),app.jointAngles(2),i,app.jointAngles(4)];
                app.updateView();
                pause(0.005);
            end
            %% link 3
            %% Link 4
            for i=15:-1:0
                app.jointAngles = [app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),i];
                app.updateView();
                pause(0.05);
            end
            for i=0:25
                app.jointAngles = [app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),i];
                app.updateView();
                pause(0.05);
            end
            %%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimonRemoteUIFigure = uifigure('Visible', 'off');
            app.SimonRemoteUIFigure.Name = 'Simon Robot';
            app.axes_arm = uiaxes(app.SimonRemoteUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.SlidersPanel = uipanel(app.SimonRemoteUIFigure);
            app.SlidersPanel.Title = 'Sliders';
            app.Joint1Slider = uislider(app.SlidersPanel);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1SliderLabel = uilabel(app.SlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint2Slider = uislider(app.SlidersPanel);
            app.Joint2Slider.Limits = [0 180];
            app.Joint2Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint2Slider.Value = 90;
            app.Joint2SliderLabel = uilabel(app.SlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint3Slider = uislider(app.SlidersPanel);
            app.Joint3Slider.Limits = [0 180];
            app.Joint3Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint3Slider.Value = 90;
            app.Joint3SliderLabel = uilabel(app.SlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint4Slider = uislider(app.SlidersPanel);
            app.Joint4Slider.Limits = [0 25];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint4Slider.Value = 15;
            app.Joint4SliderLabel = uilabel(app.SlidersPanel);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.ManualInputsPanel = uipanel(app.SimonRemoteUIFigure);
            app.ManualInputsPanel.Title = 'Manual Inputs';
            app.Joint1EditFieldLabel = uilabel(app.ManualInputsPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.ManualInputsPanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.ManualInputsPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.ManualInputsPanel, 'numeric');
            app.Joint2EditField.Value = 90;
            app.Joint3EditFieldLabel = uilabel(app.ManualInputsPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.ManualInputsPanel, 'numeric');
            app.Joint3EditField.Value = 90;
            app.ResetButton = uibutton(app.ManualInputsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GoButton = uibutton(app.ManualInputsPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.Joint4EditFieldLabel = uilabel(app.ManualInputsPanel);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.ManualInputsPanel, 'numeric');
            app.Joint4EditField.Value = 15;
            app.SavedPositionsPanel = uipanel(app.SimonRemoteUIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.GridLayout = uigridlayout(app.SavedPositionsPanel);
            app.SavePosition1Button = uibutton(app.GridLayout, 'push');
            app.SavePosition1Button.ButtonPushedFcn = createCallbackFcn(app, @SavePosition1ButtonPushed, true);
            app.SavePosition1Button.Text = 'Save Position 1';
            app.SavePosition2Button = uibutton(app.GridLayout, 'push');
            app.SavePosition2Button.ButtonPushedFcn = createCallbackFcn(app, @SavePosition2ButtonPushed, true);
            app.SavePosition2Button.Text = 'Save Position 2';
            app.SavePosition3Button = uibutton(app.GridLayout, 'push');
            app.SavePosition3Button.ButtonPushedFcn = createCallbackFcn(app, @SavePosition3ButtonPushed, true);
            app.SavePosition3Button.Text = 'Save Position 3';
            app.GotoPosition1Button = uibutton(app.GridLayout, 'push');
            app.GotoPosition1Button.ButtonPushedFcn = createCallbackFcn(app, @GotoPosition1ButtonPushed, true);
            app.GotoPosition1Button.Text = 'Go to Position 1';
            app.GotoPosition2Button = uibutton(app.GridLayout, 'push');
            app.GotoPosition2Button.ButtonPushedFcn = createCallbackFcn(app, @GotoPosition2ButtonPushed, true);
            app.GotoPosition2Button.Text = 'Go to Position 2';
            app.GotoPosition3Button = uibutton(app.GridLayout, 'push');
            app.GotoPosition3Button.ButtonPushedFcn = createCallbackFcn(app, @GotoPosition3ButtonPushed, true);
            app.GotoPosition3Button.Text = 'Go to Position 3';
            app.RangeofMotionDemonstrationButton = uibutton(app.SimonRemoteUIFigure, 'push');
            app.RangeofMotionDemonstrationButton.ButtonPushedFcn = createCallbackFcn(app, @romButtonPushed, true);
            app.RangeofMotionDemonstrationButton.Text = 'Range of Motion Demonstration';
            % Show the figure after all components are created
            app.SimonRemoteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simonRemote

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimonRemoteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimonRemoteUIFigure)
        end
    end
end