classdef tankMQTTapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exam3TankMQTTUIFigure  matlab.ui.Figure
        DISTANCELabel          matlab.ui.control.Label
        RightBackwardButton    matlab.ui.control.Button
        RightForwardButton     matlab.ui.control.Button
        BothBackwardButton     matlab.ui.control.Button
        BothForwardButton      matlab.ui.control.Button
        LeftBackwardButton     matlab.ui.control.Button
        LeftForwardButton      matlab.ui.control.Button
        RightSlider            matlab.ui.control.Slider
        RightSliderLabel       matlab.ui.control.Label
        LeftSlider             matlab.ui.control.Slider
        LeftSliderLabel        matlab.ui.control.Label
        TimeSlider             matlab.ui.control.Slider
        TimeSliderLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient

    end
    
    methods (Access = private)
        function myCallback(app,topic,message)
            % TODO: Receive Data
            messageCell = jsondecode(message)
            messageType = messageCell.type
            if messageType == "ultra"
                app.DISTANCELabel.Text = "Distance = " + messageCell.payload;
            end
        end
        function sendMqttMessage(app,type,payload)
            message = sprintf('{"type": "%s", "payload": %s}', type, payload);
            write(app.mqClient,'me435/snydergi/to_pi',message);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            subscribe(app.mqClient,'me435/snydergi/#',"Callback",@app.myCallback);
        end

        % Button pushed function: LeftForwardButton
        function LeftForwardButtonPushed(app, event)
            payload = "[1, " + app.TimeSlider.Value + ", " + app.LeftSlider.Value + "]";
            app.sendMqttMessage("left",payload);
        end

        % Button pushed function: LeftBackwardButton
        function LeftBackwardButtonPushed(app, event)
            payload = "[0, " + app.TimeSlider.Value + ", " + app.LeftSlider.Value + "]";
            app.sendMqttMessage("left",payload);
        end

        % Button pushed function: BothForwardButton
        function BothForwardButtonPushed(app, event)
            payload = "[1, " + app.TimeSlider.Value + ", " + app.LeftSlider.Value + ", " + app.RightSlider.Value + "]";
            app.sendMqttMessage("both",payload);
        end

        % Button pushed function: BothBackwardButton
        function BothBackwardButtonPushed(app, event)
            payload = "[0, " + app.TimeSlider.Value + ", " + app.LeftSlider.Value + ", " + app.RightSlider.Value + "]";
            app.sendMqttMessage("both",payload);
        end

        % Button pushed function: RightForwardButton
        function RightForwardButtonPushed(app, event)
            payload = "[1, " + app.TimeSlider.Value + ", " + app.RightSlider.Value + "]";
            app.sendMqttMessage("right",payload);
        end

        % Button pushed function: RightBackwardButton
        function RightBackwardButtonPushed(app, event)
            payload = "[0, " + app.TimeSlider.Value + ", " + app.RightSlider.Value + "]";
            app.sendMqttMessage("right",payload);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exam3TankMQTTUIFigure = uifigure('Visible', 'off');
            app.Exam3TankMQTTUIFigure.Name = 'Exam 3 Tank MQTT';
            app.TimeSliderLabel = uilabel(app.Exam3TankMQTTUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.Exam3TankMQTTUIFigure);
            app.TimeSlider.Limits = [0.5 4];
            app.TimeSlider.Value = 1;
            app.LeftSliderLabel = uilabel(app.Exam3TankMQTTUIFigure);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.Exam3TankMQTTUIFigure);
            app.LeftSlider.Limits = [0 1];
            app.LeftSlider.Value = 0.6;
            app.RightSliderLabel = uilabel(app.Exam3TankMQTTUIFigure);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.Exam3TankMQTTUIFigure);
            app.RightSlider.Limits = [0 1];
            app.RightSlider.Value = 0.6;
            app.LeftForwardButton = uibutton(app.Exam3TankMQTTUIFigure, 'push');
            app.LeftForwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftForwardButtonPushed, true);
            app.LeftForwardButton.Text = 'Left Forward';
            app.LeftBackwardButton = uibutton(app.Exam3TankMQTTUIFigure, 'push');
            app.LeftBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftBackwardButtonPushed, true);
            app.LeftBackwardButton.Text = 'Left Backward';
            app.BothForwardButton = uibutton(app.Exam3TankMQTTUIFigure, 'push');
            app.BothForwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothForwardButtonPushed, true);
            app.BothForwardButton.Text = 'Both Forward';
            app.BothBackwardButton = uibutton(app.Exam3TankMQTTUIFigure, 'push');
            app.BothBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothBackwardButtonPushed, true);
            app.BothBackwardButton.Text = 'Both Backward';
            app.RightForwardButton = uibutton(app.Exam3TankMQTTUIFigure, 'push');
            app.RightForwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightForwardButtonPushed, true);
            app.RightForwardButton.Text = 'Right Forward';
            app.RightBackwardButton = uibutton(app.Exam3TankMQTTUIFigure, 'push');
            app.RightBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightBackwardButtonPushed, true);
            app.RightBackwardButton.Text = 'Right Backward';
            app.DISTANCELabel = uilabel(app.Exam3TankMQTTUIFigure);
            app.DISTANCELabel.Text = 'DISTANCE';
            % Show the figure after all components are created
            app.Exam3TankMQTTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tankMQTTapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exam3TankMQTTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exam3TankMQTTUIFigure)
        end
    end
end