classdef tank_remote < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptRaspTankRemoteUIFigure   matlab.ui.Figure
        ModesPanel                     matlab.ui.container.Panel
        StopLineFollowingButton        matlab.ui.control.Button
        StartLineFollowingButton       matlab.ui.control.Button
        DriveUntilLineButton           matlab.ui.control.Button
        DriveUntilWallButton           matlab.ui.control.Button
        LineSensorsPanel               matlab.ui.container.Panel
        TimestampLabel                 matlab.ui.control.Label
        RightLamp                      matlab.ui.control.Lamp
        RightLampLabel                 matlab.ui.control.Label
        MiddleLamp                     matlab.ui.control.Lamp
        MiddleLampLabel                matlab.ui.control.Label
        LeftLamp                       matlab.ui.control.Lamp
        LeftLampLabel                  matlab.ui.control.Label
        StreamingLineSensorsSwitch     matlab.ui.control.Switch
        StreamingLineSensorsSwitchLabel  matlab.ui.control.Label
        SpeedSlider                    matlab.ui.control.Slider
        SpeedSliderLabel               matlab.ui.control.Label
        ArrowKeyLabel                  matlab.ui.control.Label
        DISABLEARMSERVOSButton         matlab.ui.control.Button
        SavedPositionsPanel            matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        GoToP3Button                   matlab.ui.control.Button
        SaveP3Button                   matlab.ui.control.Button
        GoToP2Button                   matlab.ui.control.Button
        SaveP2Button                   matlab.ui.control.Button
        GoToP1Button                   matlab.ui.control.Button
        SaveP1Button                   matlab.ui.control.Button
        OtherServosPanel               matlab.ui.container.Panel
        GripperSlider                  matlab.ui.control.Slider
        GripperSliderLabel             matlab.ui.control.Label
        ArmServoesasManualInputsPanel  matlab.ui.container.Panel
        GoButton                       matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Joint3EditField                matlab.ui.control.NumericEditField
        Joint3EditFieldLabel           matlab.ui.control.Label
        Joint2EditField                matlab.ui.control.NumericEditField
        Joint2EditFieldLabel           matlab.ui.control.Label
        Joint1EditField                matlab.ui.control.NumericEditField
        Joint1EditFieldLabel           matlab.ui.control.Label
        ArmServoesasSlidersPanel       matlab.ui.container.Panel
        Joint3Slider                   matlab.ui.control.Slider
        Joint3SliderLabel              matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        Joint2SliderLabel              matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint1SliderLabel              matlab.ui.control.Label
        TankRemoteLabel                matlab.ui.control.Label
        axes_arm                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        jointAngles = [0,0,0];
        gripperPos = 0.2;

        p1 = [0,0,0];
        gp1 = 0.2;
        p2 = [0,0,0];
        gp2 = 0.2;
        p3 = [0,0,0];
        gp3 = 0.2;
        
        link1Patch
        link2Patch
        link3Patch
        
        link1Vertices
        link2Vertices
        link3Vertices
        
        mqClient

    end
    
    methods (Access = private)
        function myCallback(app,topic,message)
            messageCell = jsondecode(message);
            messageType = messageCell.type;
            if messageType == "line_sensors"
               messagePayload = messageCell.payload;
               sensorValues = messagePayload.values;
               timestamp = messagePayload.timestamp;
               app.TimestampLabel.Text = timestamp;
               if sensorValues(1) == 1
                   app.LeftLamp.Color = "black";
               else
                   app.LeftLamp.Color = "white";
               end
               if sensorValues(2) == 1
                   app.MiddleLamp.Color = "black";
               else
                   app.MiddleLamp.Color = "white";
               end
               if sensorValues(3) == 1
                   app.RightLamp.Color = "black";
               else
                   app.RightLamp.Color = "white";
               end
            end
        end
        function sendMqttJoints(app)
            message.type = "joints";
            message.payload = app.jointAngles;
            write(app.mqClient,'me435/snydergi/to_pi',jsonencode(message));
        end
        function sendMqttGripper(app,inches)
            message.type = "gripper";
            message.payload = inches;
            write(app.mqClient,'me435/snydergi/to_pi',jsonencode(message));
        end
        function sendMqttArmOff(app)
            write(app.mqClient,'me435/snydergi/to_pi','{"type": "arm_off"}');
        end
        function sendMqttDrive(app, left_fwd, left_pwm, right_fwd, right_pwm)
            message.type = "drive";
            message.payload = [left_fwd, left_pwm, right_fwd, right_pwm];
            write(app.mqClient,'me435/snydergi/to_pi',jsonencode(message));
        end
        function sendMqttLineStreaming(app,value)
            message.type = "line_streaming";
            message.payload = value;
            write(app.mqClient,'me435/snydergi/to_pi',jsonencode(message));
        end
        function updateView(app)
            app.updateSliders()
            app.updateManualInputs()
            app.updateArm()
            app.sendMqttJoints()
            app.sendMqttGripper(app.gripperPos)
        end
        function updateSliders(app)
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.GripperSlider.Value = app.gripperPos;
        end
        function updateManualInputs(app)
            app.Joint1EditField.Value = app.jointAngles(1);
            app.Joint2EditField.Value = app.jointAngles(2);
            app.Joint3EditField.Value = app.jointAngles(3);
        end
        function updateArm(app)
            % Create the A homogeneous transformation matrices for the given jointAngles.
            [A1,A2,A3]=create_AdeeptArm_A_matrices(app.jointAngles);
            
            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;
            
            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            
            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)'); %app.link1Patch.Vertices
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Create MQTT Client
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            % app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            subscribe(app.mqClient,"me435/snydergi/to_computer","Callback",@app.myCallback)

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-160 250];
            app.axes_arm.YLim = [-250 250];
            app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            % End: Code that can go into your GUI's opening function.
            
            updateArm(app);
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.updateView();
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.updateView();
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.updateView();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.jointAngles = [0,0,0];
            app.gripperPos = 0.2;
            app.updateView();
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.Joint1EditField.Value;
            app.jointAngles(2) = app.Joint2EditField.Value;
            app.jointAngles(3) = app.Joint3EditField.Value;
            app.updateView();
        end

        % Value changing function: GripperSlider
        function GripperSliderValueChanging(app, event)
            changingValue = event.Value;
            app.gripperPos = changingValue;
            app.sendMqttGripper(app.gripperPos);
        end

        % Button pushed function: SaveP1Button
        function SaveP1ButtonPushed(app, event)
            app.p1 = app.jointAngles;
            app.gp1 = app.gripperPos;
            app.updateView();
        end

        % Button pushed function: GoToP1Button
        function GoToP1ButtonPushed(app, event)
            app.jointAngles = app.p1;
            app.gripperPos = app.gp1;
            app.updateView();
        end

        % Button pushed function: SaveP2Button
        function SaveP2ButtonPushed(app, event)
            app.p2 = app.jointAngles;
            app.gp2 = app.gripperPos;
            app.updateView();
        end

        % Button pushed function: GoToP2Button
        function GoToP2ButtonPushed(app, event)
            app.jointAngles = app.p2;
            app.gripperPos = app.gp2;
            app.updateView();
        end

        % Button pushed function: SaveP3Button
        function SaveP3ButtonPushed(app, event)
            app.p3 = app.jointAngles;
            app.gp3 = app.gripperPos;
            app.updateView();
        end

        % Button pushed function: GoToP3Button
        function GoToP3ButtonPushed(app, event)
            app.jointAngles = app.p3;
            app.gripperPos = app.gp3;
            app.updateView();
        end

        % Button pushed function: DISABLEARMSERVOSButton
        function DISABLEARMSERVOSButtonPushed(app, event)
            app.sendMqttArmOff();
        end

        % Key press function: AdeeptRaspTankRemoteUIFigure
        function AdeeptRaspTankRemoteUIFigureKeyPress(app, event)
            key = event.Key;
            fprintf("Key Pressed")
            if key == "uparrow"
                app.sendMqttDrive(1, app.SpeedSlider.Value, 1, app.SpeedSlider.Value);
            elseif key == "downarrow"
                app.sendMqttDrive(-1, app.SpeedSlider.Value, -1, app.SpeedSlider.Value);
            elseif key == "leftarrow"
                app.sendMqttDrive(-1, app.SpeedSlider.Value, 1, app.SpeedSlider.Value);
            elseif key == "rightarrow"
                app.sendMqttDrive(1, app.SpeedSlider.Value, -1, app.SpeedSlider.Value);
            end
        end

        % Key release function: AdeeptRaspTankRemoteUIFigure
        function AdeeptRaspTankRemoteUIFigureKeyRelease(app, event)
            key = event.Key;
            fprintf("Key Released")
            if key == "uparrow" || key == "downarrow" || key == "leftarrow" || key == "rightarrow"
                % fprintf("Send Stop \n")
                app.sendMqttDrive(0, 0, 0, 0);
            else
                fprintf("Unknown Key Pressed")
            end
        end

        % Value changed function: StreamingLineSensorsSwitch
        function StreamingLineSensorsSwitchValueChanged(app, event)
            value = app.StreamingLineSensorsSwitch.Value;
            app.sendMqttLineStreaming(value);
        end

        % Button pushed function: DriveUntilWallButton
        function DriveUntilWallButtonPushed(app, event)
            message.type = "driveWall";
            write(app.mqClient,'me435/snydergi/to_pi',jsonencode(message));
        end

        % Button pushed function: DriveUntilLineButton
        function DriveUntilLineButtonPushed(app, event)
            message.type = "driveLine";
            write(app.mqClient,'me435/snydergi/to_pi',jsonencode(message));
        end

        % Button pushed function: StartLineFollowingButton
        function StartLineFollowingButtonPushed(app, event)
            message.type = "startLine";
            write(app.mqClient,'me435/snydergi/to_pi',jsonencode(message));
        end

        % Button pushed function: StopLineFollowingButton
        function StopLineFollowingButtonPushed(app, event)
            message.type = "stopLine";
            write(app.mqClient,'me435/snydergi/to_pi',jsonencode(message));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptRaspTankRemoteUIFigure = uifigure('Visible', 'off');
            app.AdeeptRaspTankRemoteUIFigure.Name = 'Adeept RaspTank Remote';
            app.AdeeptRaspTankRemoteUIFigure.KeyPressFcn = createCallbackFcn(app, @AdeeptRaspTankRemoteUIFigureKeyPress, true);
            app.AdeeptRaspTankRemoteUIFigure.KeyReleaseFcn = createCallbackFcn(app, @AdeeptRaspTankRemoteUIFigureKeyRelease, true);
            app.axes_arm = uiaxes(app.AdeeptRaspTankRemoteUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.TankRemoteLabel = uilabel(app.AdeeptRaspTankRemoteUIFigure);
            app.TankRemoteLabel.Text = 'Tank Remote';
            app.ArmServoesasSlidersPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.ArmServoesasSlidersPanel.Title = 'Arm Servoes as Sliders';
            app.Joint1SliderLabel = uilabel(app.ArmServoesasSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ArmServoesasSlidersPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.ArmServoesasSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.ArmServoesasSlidersPanel);
            app.Joint2Slider.Limits = [-90 45];
            app.Joint2Slider.MajorTicks = [-90 -60 -30 0 30 45];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.ArmServoesasSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.ArmServoesasSlidersPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.ArmServoesasManualInputsPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.ArmServoesasManualInputsPanel.Title = 'Arm Servoes as Manual Inputs';
            app.Joint1EditFieldLabel = uilabel(app.ArmServoesasManualInputsPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.ArmServoesasManualInputsPanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.ArmServoesasManualInputsPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.ArmServoesasManualInputsPanel, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.ArmServoesasManualInputsPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.ArmServoesasManualInputsPanel, 'numeric');
            app.ResetButton = uibutton(app.ArmServoesasManualInputsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GoButton = uibutton(app.ArmServoesasManualInputsPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.OtherServosPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.OtherServosPanel.Title = 'Other Servos';
            app.GripperSliderLabel = uilabel(app.OtherServosPanel);
            app.GripperSliderLabel.Text = 'Gripper';
            app.GripperSlider = uislider(app.OtherServosPanel);
            app.GripperSlider.Limits = [0 2];
            app.GripperSlider.ValueChangingFcn = createCallbackFcn(app, @GripperSliderValueChanging, true);
            app.GripperSlider.MinorTicks = [0 0.2 0.6 1 1.4 1.8];
            app.GripperSlider.Value = 0.2;
            app.SavedPositionsPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.GridLayout = uigridlayout(app.SavedPositionsPanel);
            app.SaveP1Button = uibutton(app.GridLayout, 'push');
            app.SaveP1Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP1ButtonPushed, true);
            app.SaveP1Button.Text = 'Save P1';
            app.GoToP1Button = uibutton(app.GridLayout, 'push');
            app.GoToP1Button.ButtonPushedFcn = createCallbackFcn(app, @GoToP1ButtonPushed, true);
            app.GoToP1Button.Text = 'Go To P1';
            app.SaveP2Button = uibutton(app.GridLayout, 'push');
            app.SaveP2Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP2ButtonPushed, true);
            app.SaveP2Button.Text = 'Save P2';
            app.GoToP2Button = uibutton(app.GridLayout, 'push');
            app.GoToP2Button.ButtonPushedFcn = createCallbackFcn(app, @GoToP2ButtonPushed, true);
            app.GoToP2Button.Text = 'Go To P2';
            app.SaveP3Button = uibutton(app.GridLayout, 'push');
            app.SaveP3Button.ButtonPushedFcn = createCallbackFcn(app, @SaveP3ButtonPushed, true);
            app.SaveP3Button.Text = 'Save P3';
            app.GoToP3Button = uibutton(app.GridLayout, 'push');
            app.GoToP3Button.ButtonPushedFcn = createCallbackFcn(app, @GoToP3ButtonPushed, true);
            app.GoToP3Button.Text = 'Go To P3';
            app.DISABLEARMSERVOSButton = uibutton(app.AdeeptRaspTankRemoteUIFigure, 'push');
            app.DISABLEARMSERVOSButton.ButtonPushedFcn = createCallbackFcn(app, @DISABLEARMSERVOSButtonPushed, true);
            app.DISABLEARMSERVOSButton.Text = 'D I S A B L E     A R M     S E R V O S';
            app.ArrowKeyLabel = uilabel(app.AdeeptRaspTankRemoteUIFigure);
            app.ArrowKeyLabel.Tag = 'ArrowKeyLabel';
            app.ArrowKeyLabel.Text = 'Click below to control tank with arrow keys:';
            app.SpeedSliderLabel = uilabel(app.AdeeptRaspTankRemoteUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.AdeeptRaspTankRemoteUIFigure);
            app.SpeedSlider.Limits = [0 1];
            app.SpeedSlider.Value = 0.6;
            app.LineSensorsPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.LineSensorsPanel.Title = 'Line Sensors';
            app.StreamingLineSensorsSwitchLabel = uilabel(app.LineSensorsPanel);
            app.StreamingLineSensorsSwitchLabel.Text = 'Streaming Line Sensors';
            app.StreamingLineSensorsSwitch = uiswitch(app.LineSensorsPanel, 'slider');
            app.StreamingLineSensorsSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingLineSensorsSwitchValueChanged, true);
            app.LeftLampLabel = uilabel(app.LineSensorsPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.LineSensorsPanel);
            app.MiddleLampLabel = uilabel(app.LineSensorsPanel);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.LineSensorsPanel);
            app.RightLampLabel = uilabel(app.LineSensorsPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.LineSensorsPanel);
            app.TimestampLabel = uilabel(app.LineSensorsPanel);
            app.TimestampLabel.Text = 'Timestamp';
            app.ModesPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveUntilWallButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilWallButtonPushed, true);
            app.DriveUntilWallButton.Text = 'Drive Until Wall';
            app.DriveUntilLineButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilLineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilLineButtonPushed, true);
            app.DriveUntilLineButton.Text = 'Drive Until Line';
            app.StartLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartLineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLineFollowingButtonPushed, true);
            app.StartLineFollowingButton.Text = 'Start Line Following';
            app.StopLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StopLineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StopLineFollowingButtonPushed, true);
            app.StopLineFollowingButton.Text = 'Stop Line Following';
            % Show the figure after all components are created
            app.AdeeptRaspTankRemoteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tank_remote

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptRaspTankRemoteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptRaspTankRemoteUIFigure)
        end
    end
end