classdef gpioApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        gpioAppUIFigure      matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        CountLabel           matlab.ui.control.Label
        SendLEDsButton       matlab.ui.control.Button
        RedLEDButtonGroup    matlab.ui.container.ButtonGroup
        RedOff               matlab.ui.control.ToggleButton
        RedOn                matlab.ui.control.ToggleButton
        GreenLEDButtonGroup  matlab.ui.container.ButtonGroup
        GreenOff             matlab.ui.control.ToggleButton
        GreenOn              matlab.ui.control.ToggleButton
        BlueLEDButtonGroup   matlab.ui.container.ButtonGroup
        BlueOff              matlab.ui.control.ToggleButton
        BlueOn               matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        mqClient
        counter = 0
    end
    
    methods (Access = private)
        
        function myCallback(app,topic,message)
            messageCell = jsondecode(message)
            messageType = messageCell.type
            if messageType == "reset"
                app.counter = 0;
            end
            if messageType == "button"
                app.counter = app.counter + messageCell.payload;
            end
            % messagePayload = messageCell.payload

            % TODO: determine message TYPE before assuming payload
            % TODO: update label as appropriate

            % app.CountLabel = app.counter + 1
            app.CountLabel.Text = sprintf("Counter = %d", app.counter)
        end

        function sendMqttMessage(app,type,payload)
            message = sprintf('{"type": "%s", "payload": "%s"}', type, payload);
            write(app.mqClient,'me435/snydergi/to_pi',message);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            subscribe(app.mqClient,"me435/snydergi/to_computer","Callback",@app.myCallback)
        end

        % Selection changed function: RedLEDButtonGroup
        function RedLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.RedLEDButtonGroup.SelectedObject;
            if selectedButton == app.RedOff
                % fprintf("Send Red OFF\n")
                app.sendMqttMessage("red","off");
            else 
                % fprintf("Send Red ON\n")
                app.sendMqttMessage("red","on");
            end
        end

        % Selection changed function: GreenLEDButtonGroup
        function GreenLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.GreenLEDButtonGroup.SelectedObject;
            if selectedButton == app.GreenOff
                % fprintf("Send Red OFF\n")
                app.sendMqttMessage("green","off");
            else 
                % fprintf("Send Red ON\n")
                app.sendMqttMessage("green","on");
            end
        end

        % Selection changed function: BlueLEDButtonGroup
        function BlueLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.BlueLEDButtonGroup.SelectedObject;
            if selectedButton == app.BlueOff
                % fprintf("Send Red OFF\n")
                app.sendMqttMessage("blue","off");
            else 
                % fprintf("Send Red ON\n")
                app.sendMqttMessage("blue","on");
            end
        end

        % Button pushed function: SendLEDsButton
        function SendLEDsButtonPushed(app, event)
            payload_string = "[";
            selectedButton = app.BlueLEDButtonGroup.SelectedObject;
            if selectedButton == app.BlueOff
                payload_string = payload_string + "0, ";
            else 
                payload_string = payload_string + "1, ";
            end
            selectedButton = app.GreenLEDButtonGroup.SelectedObject;
            if selectedButton == app.GreenOff
                payload_string = payload_string + "0, ";
            else 
                payload_string = payload_string + "1, ";
            end
            selectedButton = app.RedLEDButtonGroup.SelectedObject;
            if selectedButton == app.RedOff
                payload_string = payload_string + "0";
            else 
                payload_string = payload_string + "1";
            end
            payload_string = payload_string + "]";
            message = sprintf('{"type": "%s", "payload": %s}', "leds", payload_string);
            write(app.mqClient,'me435/snydergi/to_pi',message);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.gpioAppUIFigure = uifigure('Visible', 'off');
            app.gpioAppUIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.gpioAppUIFigure);
            app.BlueLEDButtonGroup = uibuttongroup(app.GridLayout);
            app.BlueLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BlueLEDButtonGroupSelectionChanged, true);
            app.BlueLEDButtonGroup.Title = 'Blue LED';
            app.BlueOn = uitogglebutton(app.BlueLEDButtonGroup);
            app.BlueOn.Text = 'On';
            app.BlueOn.Value = true;
            app.BlueOff = uitogglebutton(app.BlueLEDButtonGroup);
            app.BlueOff.Text = 'Off';
            app.GreenLEDButtonGroup = uibuttongroup(app.GridLayout);
            app.GreenLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GreenLEDButtonGroupSelectionChanged, true);
            app.GreenLEDButtonGroup.Title = 'Green LED';
            app.GreenOn = uitogglebutton(app.GreenLEDButtonGroup);
            app.GreenOn.Text = 'On';
            app.GreenOn.Value = true;
            app.GreenOff = uitogglebutton(app.GreenLEDButtonGroup);
            app.GreenOff.Text = 'Off';
            app.RedLEDButtonGroup = uibuttongroup(app.GridLayout);
            app.RedLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RedLEDButtonGroupSelectionChanged, true);
            app.RedLEDButtonGroup.Title = 'Red LED';
            app.RedOn = uitogglebutton(app.RedLEDButtonGroup);
            app.RedOn.Text = 'On';
            app.RedOn.Value = true;
            app.RedOff = uitogglebutton(app.RedLEDButtonGroup);
            app.RedOff.Text = 'Off';
            app.SendLEDsButton = uibutton(app.GridLayout, 'push');
            app.SendLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SendLEDsButtonPushed, true);
            app.SendLEDsButton.Text = 'Send LEDs';
            app.CountLabel = uilabel(app.GridLayout);
            app.CountLabel.Text = 'Count = 0';
            % Show the figure after all components are created
            app.gpioAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gpioApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.gpioAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gpioAppUIFigure)
        end
    end
end