classdef mqttChatApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        payloadField           matlab.ui.control.EditField
        PayloadEditFieldLabel  matlab.ui.control.Label
        ReceivedText           matlab.ui.control.Label
        typeField              matlab.ui.control.EditField
        TypeEditFieldLabel     matlab.ui.control.Label
        SendButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqClient
        counter = 0
    end
    
    methods (Access = private)
        
        function myCallback(app,topic,message)
            messageCell = jsondecode(message)
            messageType = messageCell.type
            % messagePayload = messageCell.payload
            % app.ReceivedText.Text = messagePayload
            app.counter = app.counter + 1
            app.ReceivedText.Text = sprintf("Counter = %d", app.counter)
        end
        
        function sendMqttMessage(app,type,payload)
            message = sprintf('{"type": "%s", "payload": "%s"}', type, payload);
%             publish(app.mqClient,'my_messages',message);
            write(app.mqClient,'me435/snydergi/to_pi',message);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
%             subscribe(app.mqClient,'my_messages',"callback",@myCallback);
            subscribe(app.mqClient,'me435/snydergi/#',"Callback",@app.myCallback);
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.sendMqttMessage(app.typeField.Value,app.payloadField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SendButton = uibutton(app.GridLayout, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.TypeEditFieldLabel = uilabel(app.GridLayout);
            app.TypeEditFieldLabel.Text = 'Type';
            app.typeField = uieditfield(app.GridLayout, 'text');
            app.typeField.Value = 'chat';
            app.ReceivedText = uilabel(app.GridLayout);
            app.ReceivedText.Text = 'Most Recent Message';
            app.PayloadEditFieldLabel = uilabel(app.GridLayout);
            app.PayloadEditFieldLabel.Text = 'Payload';
            app.payloadField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mqttChatApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end