classdef Lab2App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ButtonGroup                   matlab.ui.container.ButtonGroup
        Button3                       matlab.ui.control.ToggleButton
        Button2                       matlab.ui.control.ToggleButton
        Button                        matlab.ui.control.ToggleButton
        plateAt5                      matlab.ui.control.Image
        plateAt4                      matlab.ui.control.Image
        plateAt3                      matlab.ui.control.Image
        plateAt2                      matlab.ui.control.Image
        plateAt1                      matlab.ui.control.Image
        ResponseBox                   matlab.ui.control.Label
        GripperClosedImage            matlab.ui.control.Image
        BarExtend                     matlab.ui.control.Image
        BarImage                      matlab.ui.control.Image
        GETSTATUSButton               matlab.ui.control.Button
        STATUSLabel                   matlab.ui.control.Label
        Background                    matlab.ui.control.Image
        resetButton                   matlab.ui.control.Button
        SelectionWindow               matlab.ui.container.TabGroup
        ProcessTab                    matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        SPECIALButton                 matlab.ui.control.Button
        GRIPPERButton                 matlab.ui.control.Button
        DELAYButton                   matlab.ui.control.Button
        ZAXISButton                   matlab.ui.control.Button
        MOVEButton                    matlab.ui.control.Button
        XAXISButton                   matlab.ui.control.Button
        XAXISTab                      matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        x5                            matlab.ui.control.Button
        x4                            matlab.ui.control.Button
        x3                            matlab.ui.control.Button
        x2                            matlab.ui.control.Button
        x1                            matlab.ui.control.Button
        ZAXISTab                      matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        retractButton                 matlab.ui.control.Button
        extendButton                  matlab.ui.control.Button
        GRIPPERTab                    matlab.ui.container.Tab
        Switch                        matlab.ui.control.Switch
        MOVETab                       matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        ActivateButton                matlab.ui.control.Button
        movePlateLabel                matlab.ui.control.Label
        toButtonGroup                 matlab.ui.container.ButtonGroup
        t5                            matlab.ui.control.RadioButton
        t4                            matlab.ui.control.RadioButton
        t3                            matlab.ui.control.RadioButton
        t2                            matlab.ui.control.RadioButton
        t1                            matlab.ui.control.RadioButton
        fromButtonGroup               matlab.ui.container.ButtonGroup
        f5                            matlab.ui.control.RadioButton
        f4                            matlab.ui.control.RadioButton
        f3                            matlab.ui.control.RadioButton
        f2                            matlab.ui.control.RadioButton
        f1                            matlab.ui.control.RadioButton
        Label                         matlab.ui.control.Label
        DELAYTab                      matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        SetButton                     matlab.ui.control.Button
        ToLabel                       matlab.ui.control.Label
        FromLabel                     matlab.ui.control.Label
        NALabel_3                     matlab.ui.control.Label
        NALabel_2                     matlab.ui.control.Label
        NALabel                       matlab.ui.control.Label
        f5t4                          matlab.ui.control.NumericEditField
        f5t3                          matlab.ui.control.NumericEditField
        f5t2                          matlab.ui.control.NumericEditField
        f4t3                          matlab.ui.control.NumericEditField
        f4t2                          matlab.ui.control.NumericEditField
        f3t4                          matlab.ui.control.NumericEditField
        f3t2                          matlab.ui.control.NumericEditField
        f2t4                          matlab.ui.control.NumericEditField
        f1t4                          matlab.ui.control.NumericEditField
        f2t3                          matlab.ui.control.NumericEditField
        f1t3                          matlab.ui.control.NumericEditField
        f1t2                          matlab.ui.control.NumericEditField
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        SPECIALTab                    matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        ChooseLocationforPlateButtonGroup  matlab.ui.container.ButtonGroup
        INSERTButton                  matlab.ui.control.Button
        plate5                        matlab.ui.control.RadioButton
        plate4                        matlab.ui.control.RadioButton
        plate3                        matlab.ui.control.RadioButton
        plate2                        matlab.ui.control.RadioButton
        plate1                        matlab.ui.control.RadioButton
        RESETDEFAULTTIMEDELAYSButton  matlab.ui.control.Button
        AnkleBreakerButton            matlab.ui.control.Button
    end


    properties (Access = public)
        robot = PlateLoaderFrontend(3) % Create Robot
%         gripperState   % defaults to closed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: XAXISButton
        function XAXISButtonPushed(app, event)
            app.SelectionWindow.SelectedTab = app.XAXISTab;
        end

        % Button pushed function: ZAXISButton
        function ZAXISButtonPushed(app, event)
            app.SelectionWindow.SelectedTab = app.ZAXISTab;
        end

        % Button pushed function: GRIPPERButton
        function GRIPPERButtonPushed(app, event)
            app.SelectionWindow.SelectedTab = app.GRIPPERTab;
        end

        % Button pushed function: MOVEButton
        function MOVEButtonPushed(app, event)
            app.SelectionWindow.SelectedTab = app.MOVETab;
        end

        % Button pushed function: DELAYButton
        function DELAYButtonPushed(app, event)
            app.SelectionWindow.SelectedTab = app.DELAYTab;
        end

        % Button pushed function: SPECIALButton
        function SPECIALButtonPushed(app, event)
            app.SelectionWindow.SelectedTab = app.SPECIALTab;
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.ResponseBox.Text = app.robot.reset;
            app.BarImage.Position = [335 330 100 100];
            app.BarExtend.Position = [335 330 100 100];
            app.GripperClosedImage.ImageSource = 'gripper_closed_no_plate.jpg'
            app.GripperClosedImage.Position = [335 300 100 100];
            app.Switch.Value = "CLOSED";
        end

        % Button pushed function: x1
        function x1ButtonPushed(app, event)
            load train.mat;
            sound(y,Fs);
            app.ResponseBox.Text = app.robot.x(1);
            app.SelectionWindow.SelectedTab = app.ProcessTab;
            app.BarImage.Position = [155 330 100 100];
            app.BarExtend.Position = [155 app.BarExtend.Position(2) 100 100];
            app.GripperClosedImage.Position = [155 app.GripperClosedImage.Position(2) 100 100];
        end

        % Button pushed function: x2
        function x2ButtonPushed(app, event)
            load train.mat;
            sound(y,Fs);
            app.ResponseBox.Text = app.robot.x(2);
            app.SelectionWindow.SelectedTab = app.ProcessTab;
            app.BarImage.Position = [245 330 100 100];
            app.BarExtend.Position = [245 app.BarExtend.Position(2) 100 100];
            app.GripperClosedImage.Position = [245 app.GripperClosedImage.Position(2) 100 100];
        end

        % Button pushed function: x3
        function x3ButtonPushed(app, event)
            load train.mat;
            sound(y,Fs);
            app.ResponseBox.Text = app.robot.x(3);
            app.SelectionWindow.SelectedTab = app.ProcessTab;
            app.BarImage.Position = [335 330 100 100];
            app.BarExtend.Position = [335 app.BarExtend.Position(2) 100 100];
            app.GripperClosedImage.Position = [335 app.GripperClosedImage.Position(2) 100 100];
        end

        % Button pushed function: x4
        function x4ButtonPushed(app, event)
            load train.mat;
            sound(y,Fs);
            app.ResponseBox.Text = app.robot.x(4);
            app.SelectionWindow.SelectedTab = app.ProcessTab;
            app.BarImage.Position = [425 330 100 100];
            app.BarExtend.Position = [425 app.BarExtend.Position(2) 100 100];
            app.GripperClosedImage.Position = [425 app.GripperClosedImage.Position(2) 100 100];
        end

        % Button pushed function: x5
        function x5ButtonPushed(app, event)
            load train.mat;
            sound(y,Fs);
            app.ResponseBox.Text = app.robot.x(5);
            app.SelectionWindow.SelectedTab = app.ProcessTab;
            app.BarImage.Position = [515 330 100 100];
            app.BarExtend.Position = [515 app.BarExtend.Position(2) 100 100];
            app.GripperClosedImage.Position = [515 app.GripperClosedImage.Position(2) 100 100];
        end

        % Button pushed function: extendButton
        function extendButtonPushed(app, event)
            app.ResponseBox.Text = app.robot.extend;
            app.SelectionWindow.SelectedTab = app.ProcessTab;
            app.BarExtend.Position = [app.BarExtend.Position(1) 280 100 100];
            app.GripperClosedImage.Position = [app.GripperClosedImage.Position(1) 250 100 100];
        end

        % Button pushed function: retractButton
        function retractButtonPushed(app, event)
            app.ResponseBox.Text = app.robot.retract;
            app.SelectionWindow.SelectedTab = app.ProcessTab;
            app.BarExtend.Position = [app.BarExtend.Position(1) 330 100 100];
            app.GripperClosedImage.Position = [app.GripperClosedImage.Position(1) 300 100 100];
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmpi(value,"CLOSED")
                app.ResponseBox.Text = app.robot.close;
                [~,~,~,isPlatePresent] = app.robot.getProperties;
                if isPlatePresent == true
                    app.GripperClosedImage.ImageSource = 'gripper_with_plate.jpg';
                    xPos = app.robot.getProperties;
                    if xPos == 1
                        app.plateAt1.Visible = false;
                    elseif xPos == 2
                        app.plateAt2.Visible = false;
                    elseif xPos == 3
                        app.plateAt3.Visible = false;
                    elseif xPos == 4
                        app.plateAt4.Visible = false;
                    elseif xPos == 5
                        app.plateAt5.Visible = false;
                    end
                else
                    app.GripperClosedImage.ImageSource = 'gripper_closed_no_plate.jpg' ;
                end
                app.SelectionWindow.SelectedTab = app.ProcessTab;
            end
            if strcmpi(value,"OPEN")
                [~,~,~,isPlatePresent] = app.robot.getProperties;
                app.ResponseBox.Text = app.robot.open;
                app.GripperClosedImage.ImageSource = 'gripper_open_no_plate.jpg' ;
                if isPlatePresent == true
                    xPos = app.robot.getProperties;
                    if xPos == 1
                        app.plateAt1.Visible = true;
                        load gong.mat;
                        sound(y,Fs);
                    elseif xPos == 2
                        app.plateAt2.Visible = true;
                        load gong.mat;
                        sound(y,Fs);
                    elseif xPos == 3
                        app.plateAt3.Visible = true;
                        load gong.mat;
                        sound(y,Fs);
                    elseif xPos == 4
                        app.plateAt4.Visible = true;
                        load gong.mat;
                        sound(y,Fs);
                    elseif xPos == 5
                        app.plateAt5.Visible = true;
                        load gong.mat;
                        sound(y,Fs);
                    end
                else
                    
                end
                app.SelectionWindow.SelectedTab = app.ProcessTab;
            end
        end

        % Button pushed function: GETSTATUSButton
        function GETSTATUSButtonPushed(app, event)
            app.STATUSLabel.Text = app.robot.getStatus;
            app.ResponseBox.Text = app.robot.getStatus;
        end

        % Button pushed function: ActivateButton
        function ActivateButtonPushed(app, event)
            fromNum = str2num(app.fromButtonGroup.SelectedObject.Text);
            toNum = str2num(app.toButtonGroup.SelectedObject.Text);
            app.robot.movePlate(fromNum,toNum);
            app.plateAt1.Visible = false;
            app.plateAt2.Visible = false;
            app.plateAt3.Visible = false;
            app.plateAt4.Visible = false;
            app.plateAt5.Visible = false;
            if fromNum == 1
                app.plateAt1.Visible = true;
                pause(0.5)
                app.BarImage.Position = [155 330 100 100];
                app.BarExtend.Position = [155 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [155 app.GripperClosedImage.Position(2) 100 100];
            elseif  fromNum == 2
                app.plateAt2.Visible = true;
                pause(0.5)
                app.BarImage.Position = [245 330 100 100];
                app.BarExtend.Position = [245 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [245 app.GripperClosedImage.Position(2) 100 100];
            elseif  fromNum == 3
                app.plateAt3.Visible = true;
                pause(0.5)
                app.BarImage.Position = [335 330 100 100];
                app.BarExtend.Position = [335 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [335 app.GripperClosedImage.Position(2) 100 100];
            elseif  fromNum == 4
                app.plateAt4.Visible = true;
                pause(0.5)
                app.BarImage.Position = [425 330 100 100];
                app.BarExtend.Position = [425 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [425 app.GripperClosedImage.Position(2) 100 100];
            elseif  fromNum == 5
                app.plateAt5.Visible = true;
                pause(0.5)
                app.BarImage.Position = [515 330 100 100];
                app.BarExtend.Position = [515 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [515 app.GripperClosedImage.Position(2) 100 100];
            end
            pause(0.5)
            app.ResponseBox.Text = app.robot.open;
            app.GripperClosedImage.ImageSource = 'gripper_open_no_plate.jpg' ;
            pause(0.5)
            app.ResponseBox.Text = app.robot.extend;
            app.BarExtend.Position = [app.BarExtend.Position(1) 280 100 100];
            app.GripperClosedImage.Position = [app.GripperClosedImage.Position(1) 250 100 100];
            pause(0.5)
            app.ResponseBox.Text = app.robot.close;
            app.GripperClosedImage.ImageSource = 'gripper_with_plate.jpg';
            app.plateAt1.Visible = false;
            app.plateAt2.Visible = false;
            app.plateAt3.Visible = false;
            app.plateAt4.Visible = false;
            app.plateAt5.Visible = false;
            pause(0.5)
            app.ResponseBox.Text = app.robot.retract;
            app.BarExtend.Position = [app.BarExtend.Position(1) 330 100 100];
            app.GripperClosedImage.Position = [app.GripperClosedImage.Position(1) 300 100 100];
            pause(0.5)
            
            if toNum == 1
                app.BarImage.Position = [155 330 100 100];
                app.BarExtend.Position = [155 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [155 app.GripperClosedImage.Position(2) 100 100];
            elseif  toNum == 2
                app.BarImage.Position = [245 330 100 100];
                app.BarExtend.Position = [245 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [245 app.GripperClosedImage.Position(2) 100 100];
            elseif  toNum == 3
                app.BarImage.Position = [335 330 100 100];
                app.BarExtend.Position = [335 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [335 app.GripperClosedImage.Position(2) 100 100];
            elseif  toNum == 4
                app.BarImage.Position = [425 330 100 100];
                app.BarExtend.Position = [425 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [425 app.GripperClosedImage.Position(2) 100 100];
            elseif  toNum == 5
                app.BarImage.Position = [515 330 100 100];
                app.BarExtend.Position = [515 app.BarExtend.Position(2) 100 100];
                app.GripperClosedImage.Position = [515 app.GripperClosedImage.Position(2) 100 100];
            end
            pause(0.5)
            app.ResponseBox.Text = app.robot.extend;
            app.BarExtend.Position = [app.BarExtend.Position(1) 280 100 100];
            app.GripperClosedImage.Position = [app.GripperClosedImage.Position(1) 250 100 100];
            pause(0.5)
            app.ResponseBox.Text = app.robot.open;
            app.GripperClosedImage.ImageSource = 'gripper_open_no_plate.jpg' ;
            
            if toNum == 1
                app.plateAt1.Visible = true;
            elseif toNum == 2
                app.plateAt2.Visible = true;
            elseif toNum == 3
                app.plateAt3.Visible = true;
            elseif toNum == 4
                app.plateAt4.Visible = true;
            elseif toNum == 5
                app.plateAt5.Visible = true;
            end    
            load gong.mat;
            sound(y,Fs);
            app.ResponseBox.Text = app.robot.retract;
            app.BarExtend.Position = [app.BarExtend.Position(1) 330 100 100];
            app.GripperClosedImage.Position = [app.GripperClosedImage.Position(1) 300 100 100];
            
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            timeDelaysTable = [0 app.f1t2.Value app.f1t3.Value app.f1t4.Value 0;
                0 0 app.f2t3.Value app.f2t4.Value 0;
                0 app.f3t2.Value 0 app.f3t4.Value 0;
                0 app.f4t2.Value app.f4t3.Value 0 0;
                0 app.f5t2.Value app.f5t3.Value app.f5t4.Value 0];
            app.robot.setTimeValues(timeDelaysTable);
            app.ResponseBox.Text = 'Response too long to display';
            app.SelectionWindow.SelectedTab = app.ProcessTab;
        end

        % Button pushed function: RESETDEFAULTTIMEDELAYSButton
        function RESETDEFAULTTIMEDELAYSButtonPushed(app, event)
            app.robot.resetDefaultTimes;
            app.SelectionWindow.SelectedTab = app.ProcessTab;
            load handel.mat;
            sound(y,Fs);
        end

        % Button pushed function: INSERTButton
        function INSERTButtonPushed(app, event)
            if strcmpi(app.ChooseLocationforPlateButtonGroup.SelectedObject.Text,"1")
                app.plateAt1.Visible = true;
            elseif strcmpi(app.ChooseLocationforPlateButtonGroup.SelectedObject.Text,"2")
                app.plateAt2.Visible = true;
            elseif strcmpi(app.ChooseLocationforPlateButtonGroup.SelectedObject.Text,"3")
                app.plateAt3.Visible = true;
            elseif strcmpi(app.ChooseLocationforPlateButtonGroup.SelectedObject.Text,"4")
                app.plateAt4.Visible = true;
            elseif strcmpi(app.ChooseLocationforPlateButtonGroup.SelectedObject.Text,"5")
                app.plateAt5.Visible = true;
            end
        end

        % Button pushed function: AnkleBreakerButton
        function AnkleBreakerButtonPushed(app, event)
            app.plateAt1.Visible = true;
            pause(0.5)
            app.ResponseBox.Text = app.robot.x(1);
            app.BarImage.Position = [155 330 100 100];
            app.BarExtend.Position = [155 app.BarExtend.Position(2) 100 100];
            app.GripperClosedImage.Position = [155 app.GripperClosedImage.Position(2) 100 100];
            pause(0.5)
            app.ResponseBox.Text = app.robot.open;
            app.GripperClosedImage.ImageSource = 'gripper_open_no_plate.jpg' ;
            pause(0.5)
            app.ResponseBox.Text = app.robot.extend;
            app.BarExtend.Position = [app.BarExtend.Position(1) 280 100 100];
            app.GripperClosedImage.Position = [app.GripperClosedImage.Position(1) 250 100 100];
            pause(0.5)
            app.ResponseBox.Text = app.robot.close;
            app.GripperClosedImage.ImageSource = 'gripper_with_plate.jpg';
            app.plateAt1.Visible = false;
            pause(0.5)
            app.ResponseBox.Text = app.robot.retract;
            app.BarExtend.Position = [app.BarExtend.Position(1) 330 100 100];
            app.GripperClosedImage.Position = [app.GripperClosedImage.Position(1) 300 100 100];
            pause(0.5)
            app.ResponseBox.Text = app.robot.x(5);
            app.BarImage.Position = [515 330 100 100];
            app.BarExtend.Position = [515 app.BarExtend.Position(2) 100 100];
            app.GripperClosedImage.Position = [515 app.GripperClosedImage.Position(2) 100 100];
            pause(0.5)
            app.ResponseBox.Text = app.robot.x(1);
            app.BarImage.Position = [155 330 100 100];
            app.BarExtend.Position = [155 app.BarExtend.Position(2) 100 100];
            app.GripperClosedImage.Position = [155 app.GripperClosedImage.Position(2) 100 100];
            pause(0.5)
            app.ResponseBox.Text = app.robot.open;
            app.GripperClosedImage.ImageSource = 'gripper_open_no_plate.jpg' ;
            app.plateAt1.Visible = true;
            load gong.mat;
            sound(y,Fs);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectionWindow = uitabgroup(app.UIFigure);
            app.ProcessTab = uitab(app.SelectionWindow);
            app.ProcessTab.Title = 'Process:';
            app.GridLayout4 = uigridlayout(app.ProcessTab);
            app.XAXISButton = uibutton(app.GridLayout4, 'push');
            app.XAXISButton.ButtonPushedFcn = createCallbackFcn(app, @XAXISButtonPushed, true);
            app.XAXISButton.Text = 'X-AXIS';
            app.MOVEButton = uibutton(app.GridLayout4, 'push');
            app.MOVEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEButtonPushed, true);
            app.MOVEButton.Text = 'MOVE';
            app.ZAXISButton = uibutton(app.GridLayout4, 'push');
            app.ZAXISButton.ButtonPushedFcn = createCallbackFcn(app, @ZAXISButtonPushed, true);
            app.ZAXISButton.Text = 'Z-AXIS';
            app.DELAYButton = uibutton(app.GridLayout4, 'push');
            app.DELAYButton.ButtonPushedFcn = createCallbackFcn(app, @DELAYButtonPushed, true);
            app.DELAYButton.Text = 'DELAY';
            app.GRIPPERButton = uibutton(app.GridLayout4, 'push');
            app.GRIPPERButton.ButtonPushedFcn = createCallbackFcn(app, @GRIPPERButtonPushed, true);
            app.GRIPPERButton.Text = 'GRIPPER';
            app.SPECIALButton = uibutton(app.GridLayout4, 'push');
            app.SPECIALButton.ButtonPushedFcn = createCallbackFcn(app, @SPECIALButtonPushed, true);
            app.SPECIALButton.Text = 'SPECIAL';
            app.XAXISTab = uitab(app.SelectionWindow);
            app.XAXISTab.Title = 'X-AXIS';
            app.GridLayout = uigridlayout(app.XAXISTab);
            app.x1 = uibutton(app.GridLayout, 'push');
            app.x1.ButtonPushedFcn = createCallbackFcn(app, @x1ButtonPushed, true);
            app.x1.Text = '1';
            app.x2 = uibutton(app.GridLayout, 'push');
            app.x2.ButtonPushedFcn = createCallbackFcn(app, @x2ButtonPushed, true);
            app.x2.Text = '2';
            app.x3 = uibutton(app.GridLayout, 'push');
            app.x3.ButtonPushedFcn = createCallbackFcn(app, @x3ButtonPushed, true);
            app.x3.Text = '3';
            app.x4 = uibutton(app.GridLayout, 'push');
            app.x4.ButtonPushedFcn = createCallbackFcn(app, @x4ButtonPushed, true);
            app.x4.Text = '4';
            app.x5 = uibutton(app.GridLayout, 'push');
            app.x5.ButtonPushedFcn = createCallbackFcn(app, @x5ButtonPushed, true);
            app.x5.Text = '5';
            app.ZAXISTab = uitab(app.SelectionWindow);
            app.ZAXISTab.Title = 'Z-AXIS';
            app.GridLayout2 = uigridlayout(app.ZAXISTab);
            app.extendButton = uibutton(app.GridLayout2, 'push');
            app.extendButton.ButtonPushedFcn = createCallbackFcn(app, @extendButtonPushed, true);
            app.extendButton.Text = 'EXTEND';
            app.retractButton = uibutton(app.GridLayout2, 'push');
            app.retractButton.ButtonPushedFcn = createCallbackFcn(app, @retractButtonPushed, true);
            app.retractButton.Text = 'RETRACT';
            app.GRIPPERTab = uitab(app.SelectionWindow);
            app.GRIPPERTab.Title = 'GRIPPER';
            app.Switch = uiswitch(app.GRIPPERTab, 'slider');
            app.Switch.Items = {'CLOSED', 'OPEN'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'CLOSED';
            app.MOVETab = uitab(app.SelectionWindow);
            app.MOVETab.Title = 'MOVE';
            app.GridLayout3 = uigridlayout(app.MOVETab);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = {'NOTE: Ensure plate present in ''From'' location, and '; '            that ''To'' position is clear'};
            app.fromButtonGroup = uibuttongroup(app.GridLayout3);
            app.fromButtonGroup.Title = 'FROM:';
            app.f1 = uiradiobutton(app.fromButtonGroup);
            app.f1.Text = '1';
            app.f1.Value = true;
            app.f2 = uiradiobutton(app.fromButtonGroup);
            app.f2.Text = '2';
            app.f3 = uiradiobutton(app.fromButtonGroup);
            app.f3.Text = '3';
            app.f4 = uiradiobutton(app.fromButtonGroup);
            app.f4.Text = '4';
            app.f5 = uiradiobutton(app.fromButtonGroup);
            app.f5.Text = '5';
            app.toButtonGroup = uibuttongroup(app.GridLayout3);
            app.toButtonGroup.Title = 'TO:';
            app.t1 = uiradiobutton(app.toButtonGroup);
            app.t1.Text = '1';
            app.t1.Value = true;
            app.t2 = uiradiobutton(app.toButtonGroup);
            app.t2.Text = '2';
            app.t3 = uiradiobutton(app.toButtonGroup);
            app.t3.Text = '3';
            app.t4 = uiradiobutton(app.toButtonGroup);
            app.t4.Text = '4';
            app.t5 = uiradiobutton(app.toButtonGroup);
            app.t5.Text = '5';
            app.movePlateLabel = uilabel(app.GridLayout3);
            app.movePlateLabel.Text = 'MOVE PLATE:';
            app.ActivateButton = uibutton(app.GridLayout3, 'push');
            app.ActivateButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateButtonPushed, true);
            app.ActivateButton.Text = 'Activate';
            app.DELAYTab = uitab(app.SelectionWindow);
            app.DELAYTab.Title = 'DELAY';
            app.GridLayout5 = uigridlayout(app.DELAYTab);
            app.Label_2 = uilabel(app.GridLayout5);
            app.Label_2.Text = '1';
            app.Label_6 = uilabel(app.GridLayout5);
            app.Label_6.Text = '2';
            app.Label_7 = uilabel(app.GridLayout5);
            app.Label_7.Text = '3';
            app.Label_8 = uilabel(app.GridLayout5);
            app.Label_8.Text = '4';
            app.Label_9 = uilabel(app.GridLayout5);
            app.Label_9.Text = '5';
            app.Label_3 = uilabel(app.GridLayout5);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.GridLayout5);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.GridLayout5);
            app.Label_5.Text = '4';
            app.f1t2 = uieditfield(app.GridLayout5, 'numeric');
            app.f1t2.Value = 60;
            app.f1t3 = uieditfield(app.GridLayout5, 'numeric');
            app.f1t3.Value = 20;
            app.f2t3 = uieditfield(app.GridLayout5, 'numeric');
            app.f2t3.Value = 30;
            app.f1t4 = uieditfield(app.GridLayout5, 'numeric');
            app.f1t4.Value = 30;
            app.f2t4 = uieditfield(app.GridLayout5, 'numeric');
            app.f2t4.Value = 30;
            app.f3t2 = uieditfield(app.GridLayout5, 'numeric');
            app.f3t2.Value = 30;
            app.f3t4 = uieditfield(app.GridLayout5, 'numeric');
            app.f3t4.Value = 30;
            app.f4t2 = uieditfield(app.GridLayout5, 'numeric');
            app.f4t2.Value = 30;
            app.f4t3 = uieditfield(app.GridLayout5, 'numeric');
            app.f4t3.Value = 30;
            app.f5t2 = uieditfield(app.GridLayout5, 'numeric');
            app.f5t2.Value = 30;
            app.f5t3 = uieditfield(app.GridLayout5, 'numeric');
            app.f5t3.Value = 20;
            app.f5t4 = uieditfield(app.GridLayout5, 'numeric');
            app.f5t4.Value = 60;
            app.NALabel = uilabel(app.GridLayout5);
            app.NALabel.Text = 'N/A';
            app.NALabel_2 = uilabel(app.GridLayout5);
            app.NALabel_2.Text = 'N/A';
            app.NALabel_3 = uilabel(app.GridLayout5);
            app.NALabel_3.Text = 'N/A';
            app.FromLabel = uilabel(app.GridLayout5);
            app.FromLabel.Text = 'From:';
            app.ToLabel = uilabel(app.GridLayout5);
            app.ToLabel.Text = 'To:';
            app.SetButton = uibutton(app.GridLayout5, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.SPECIALTab = uitab(app.SelectionWindow);
            app.SPECIALTab.Title = 'SPECIAL';
            app.GridLayout6 = uigridlayout(app.SPECIALTab);
            app.AnkleBreakerButton = uibutton(app.GridLayout6, 'push');
            app.AnkleBreakerButton.ButtonPushedFcn = createCallbackFcn(app, @AnkleBreakerButtonPushed, true);
            app.AnkleBreakerButton.Text = 'Ankle Breaker';
            app.RESETDEFAULTTIMEDELAYSButton = uibutton(app.GridLayout6, 'push');
            app.RESETDEFAULTTIMEDELAYSButton.ButtonPushedFcn = createCallbackFcn(app, @RESETDEFAULTTIMEDELAYSButtonPushed, true);
            app.RESETDEFAULTTIMEDELAYSButton.Text = 'RESET DEFAULT TIME DELAYS';
            app.ChooseLocationforPlateButtonGroup = uibuttongroup(app.GridLayout6);
            app.ChooseLocationforPlateButtonGroup.Title = 'Choose Location for Plate:';
            app.plate1 = uiradiobutton(app.ChooseLocationforPlateButtonGroup);
            app.plate1.Text = '1';
            app.plate1.Value = true;
            app.plate2 = uiradiobutton(app.ChooseLocationforPlateButtonGroup);
            app.plate2.Text = '2';
            app.plate3 = uiradiobutton(app.ChooseLocationforPlateButtonGroup);
            app.plate3.Text = '3';
            app.plate4 = uiradiobutton(app.ChooseLocationforPlateButtonGroup);
            app.plate4.Text = '4';
            app.plate5 = uiradiobutton(app.ChooseLocationforPlateButtonGroup);
            app.plate5.Text = '5';
            app.INSERTButton = uibutton(app.ChooseLocationforPlateButtonGroup, 'push');
            app.INSERTButton.ButtonPushedFcn = createCallbackFcn(app, @INSERTButtonPushed, true);
            app.INSERTButton.Text = 'INSERT';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Icon = 'resetButtonPNG.png';
            app.resetButton.Text = '';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'robot_background.jpg';
            app.STATUSLabel = uilabel(app.UIFigure);
            app.STATUSLabel.Text = 'STATUS';
            app.GETSTATUSButton = uibutton(app.UIFigure, 'push');
            app.GETSTATUSButton.ButtonPushedFcn = createCallbackFcn(app, @GETSTATUSButtonPushed, true);
            app.GETSTATUSButton.Text = 'GET STATUS';
            app.BarImage = uiimage(app.UIFigure);
            app.BarImage.ImageSource = 'extended_bars.jpg';
            app.BarExtend = uiimage(app.UIFigure);
            app.BarExtend.ImageSource = 'extended_bars.jpg';
            app.GripperClosedImage = uiimage(app.UIFigure);
            app.GripperClosedImage.ImageSource = 'gripper_closed_no_plate.jpg';
            app.ResponseBox = uilabel(app.UIFigure);
            app.ResponseBox.Text = '''READY, SAGIAN PE Loader, ROM Ver. 1.1.6 12APR2001''';
            app.plateAt1 = uiimage(app.UIFigure);
            app.plateAt1.Visible = 'off';
            app.plateAt1.ImageSource = 'plate_only.jpg';
            app.plateAt2 = uiimage(app.UIFigure);
            app.plateAt2.Visible = 'off';
            app.plateAt2.ImageSource = 'plate_only.jpg';
            app.plateAt3 = uiimage(app.UIFigure);
            app.plateAt3.Visible = 'off';
            app.plateAt3.ImageSource = 'plate_only.jpg';
            app.plateAt4 = uiimage(app.UIFigure);
            app.plateAt4.Visible = 'off';
            app.plateAt4.ImageSource = 'plate_only.jpg';
            app.plateAt5 = uiimage(app.UIFigure);
            app.plateAt5.Visible = 'off';
            app.plateAt5.ImageSource = 'plate_only.jpg';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab2App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end