classdef plateLoaderGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        MoveXaxisButton     matlab.ui.control.Button
        Image               matlab.ui.control.Image
        StatusButton        matlab.ui.control.Button
        Label               matlab.ui.control.Label
        XAXISKnobLabel      matlab.ui.control.Label
        XAXISKnob           matlab.ui.control.DiscreteKnob
        GripperSwitch       matlab.ui.control.RockerSwitch
        GripperSwitchLabel  matlab.ui.control.Label
        ToDropDown          matlab.ui.control.DropDown
        ToDropDownLabel     matlab.ui.control.Label
        FromDropDown        matlab.ui.control.DropDown
        FromDropDownLabel   matlab.ui.control.Label
        MovePlateButton     matlab.ui.control.Button
        ExtendButton        matlab.ui.control.Button
        RetractButton       matlab.ui.control.Button
        ZAXISLabel          matlab.ui.control.Label
        RESETButton         matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        MovePlateLabel      matlab.ui.control.Label
        AnkleBreakerButton  matlab.ui.control.Button
        Special2Button      matlab.ui.control.Button
        SetDelayButton      matlab.ui.control.Button
        ResetDelayButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        robot = PlateLoaderSim(1)
        xVal = 0;
        fromVal = 0;
        toVal = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: RetractButton
        function RetractButtonPushed(app, event)
            app.robot.retract
        end

        % Button pushed function: ExtendButton
        function ExtendButtonPushed(app, event)
            app.robot.extend
        end

        % Callback function
        function OpenButtonPushed(app, event)
            app.robot.open
        end

        % Value changed function: GripperSwitch
        function CloseButtonPushed(app, event)
            app.robot.close
        end

        % Value changed function: FromDropDown
        function FromDropDownValueChanged(app, event)
            app.fromVal = str2double(app.FromDropDown.Value);
            fprintf("from: " + app.fromVal  +"\n")
        end

        % Drop down opening function: FromDropDown
        function FromDropDownOpening(app, event)
            app.fromVal = 0;
        end

        % Value changed function: ToDropDown
        function ToDropDownValueChanged(app, event)
            app.toVal = str2double(app.ToDropDown.Value);
            fprintf("to: " + app.toVal  +"\n")
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.robot.reset
        end

        % Value changed function: XAXISKnob
        function XAXISKnobValueChanged(app, event)
            app.xVal = str2double(app.XAXISKnob.Value);
            fprintf(app.xVal)
        end

        % Button pushed function: StatusButton
        function StatusButtonPushed(app, event)
            [xAxis, zAxisExtended, gripperClosed, platePresent] = app.robot.getProperties
        end

        % Button pushed function: MoveXaxisButton
        function MoveXaxisButtonPushed(app, event)
            app.robot.x(app.xVal)
        end

        % Button pushed function: MovePlateButton
        function MovePlateButtonPushed(app, event)
            app.robot.movePlate(app.fromVal,app.toVal)
        end

        % Drop down opening function: ToDropDown
        function ToDropDownOpening(app, event)
            app.toVal = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResetDelayButton = uibutton(app.UIFigure, 'push');
            app.ResetDelayButton.Text = 'Reset Delay';
            app.SetDelayButton = uibutton(app.UIFigure, 'push');
            app.SetDelayButton.Text = 'Set Delay';
            app.Special2Button = uibutton(app.UIFigure, 'push');
            app.Special2Button.Text = 'Special 2';
            app.AnkleBreakerButton = uibutton(app.UIFigure, 'push');
            app.AnkleBreakerButton.Text = 'Ankle Breaker';
            app.MovePlateLabel = uilabel(app.UIFigure);
            app.MovePlateLabel.Text = 'Move Plate';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; '2'; '3'; '4'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'; ''};
            app.UITable.ColumnEditable = true;
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ZAXISLabel = uilabel(app.UIFigure);
            app.ZAXISLabel.Text = 'Z-AXIS';
            app.RetractButton = uibutton(app.UIFigure, 'push');
            app.RetractButton.ButtonPushedFcn = createCallbackFcn(app, @RetractButtonPushed, true);
            app.RetractButton.Text = 'Retract';
            app.ExtendButton = uibutton(app.UIFigure, 'push');
            app.ExtendButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendButtonPushed, true);
            app.ExtendButton.Text = 'Extend';
            app.MovePlateButton = uibutton(app.UIFigure, 'push');
            app.MovePlateButton.ButtonPushedFcn = createCallbackFcn(app, @MovePlateButtonPushed, true);
            app.MovePlateButton.Text = 'Move Plate';
            app.FromDropDownLabel = uilabel(app.UIFigure);
            app.FromDropDownLabel.Text = 'From';
            app.FromDropDown = uidropdown(app.UIFigure);
            app.FromDropDown.Items = {'1', '2', '3', '4', '5'};
            app.FromDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FromDropDownOpening, true);
            app.FromDropDown.ValueChangedFcn = createCallbackFcn(app, @FromDropDownValueChanged, true);
            app.FromDropDown.Value = '1';
            app.ToDropDownLabel = uilabel(app.UIFigure);
            app.ToDropDownLabel.Text = 'To';
            app.ToDropDown = uidropdown(app.UIFigure);
            app.ToDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ToDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ToDropDownOpening, true);
            app.ToDropDown.ValueChangedFcn = createCallbackFcn(app, @ToDropDownValueChanged, true);
            app.ToDropDown.Value = '1';
            app.GripperSwitchLabel = uilabel(app.UIFigure);
            app.GripperSwitchLabel.Text = 'Gripper';
            app.GripperSwitch = uiswitch(app.UIFigure, 'rocker');
            app.GripperSwitch.Items = {'Close', 'Open'};
            app.GripperSwitch.ValueChangedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.GripperSwitch.Value = 'Close';
            app.XAXISKnob = uiknob(app.UIFigure, 'discrete');
            app.XAXISKnob.Items = {'1', '2', '3', '4', '5'};
            app.XAXISKnob.ValueChangedFcn = createCallbackFcn(app, @XAXISKnobValueChanged, true);
            app.XAXISKnob.Value = '5';
            app.XAXISKnobLabel = uilabel(app.UIFigure);
            app.XAXISKnobLabel.Text = 'X-AXIS';
            app.Label = uilabel(app.UIFigure);
            app.StatusButton = uibutton(app.UIFigure, 'push');
            app.StatusButton.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.StatusButton.Text = 'Status';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'robot_background.jpg';
            app.MoveXaxisButton = uibutton(app.UIFigure, 'push');
            app.MoveXaxisButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXaxisButtonPushed, true);
            app.MoveXaxisButton.Text = 'Move X-axis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plateLoaderGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end